classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        LabelSlider            matlab.ui.control.Label            % Angle
        Slider                 matlab.ui.control.Slider           % [5 85]
        Label                  matlab.ui.control.Label            % SMILE P...
        Label2                 matlab.ui.control.Label            % Facial ...
        CheckBox               matlab.ui.control.CheckBox         % True
        Label3                 matlab.ui.control.Label            % Is Idea...
        LabelNumericEditField  matlab.ui.control.Label            % Distanc...
        NumericEditField       matlab.ui.control.NumericEditField % [120 500]
        LabelNumericEditField3 matlab.ui.control.Label            % FOV Camera
        NumericEditField3      matlab.ui.control.NumericEditField % [20 180]
        Button                 matlab.ui.control.Button           % Simulate
        Label4                 matlab.ui.control.Label            % Sistem ...
        Label5                 matlab.ui.control.Label            % Length ...
        NumericEditField4      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label6                 matlab.ui.control.Label            % Pivot t...
        NumericEditField5      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label7                 matlab.ui.control.Label            % Offset ...
        NumericEditField6      matlab.ui.control.NumericEditField % [-Inf Inf]
        Label9                 matlab.ui.control.Label            % Mirror ...
        NumericEditField8      matlab.ui.control.NumericEditField % [-Inf Inf]
        TabGroup               matlab.ui.container.TabGroup       % Visible...
        Tab                    matlab.ui.container.Tab            % Visible...
        UIAxes                 matlab.ui.control.UIAxes          
        Tab2                   matlab.ui.container.Tab            % Full Co...
        UIAxes2                matlab.ui.control.UIAxes          
        TextArea               matlab.ui.control.TextArea        
        Label10                matlab.ui.control.Label            % Head X
        NumericEditField9      matlab.ui.control.NumericEditField % [10 180]
        Label11                matlab.ui.control.Label            % Head Y
        NumericEditField10     matlab.ui.control.NumericEditField % [10 180]
        Tab3                   matlab.ui.container.Tab            % Evaluation
        UIAxes3                matlab.ui.control.UIAxes          
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function run(app)
            anglebetweenmirrors = app.Slider.Value;
            isIdealCamera = app.CheckBox.Value;
            distancecamera = app.NumericEditField.Value;
            fovcamera = app.NumericEditField3.Value;
            lengthmirror = app.NumericEditField4.Value;
            pivot2pivotdistance = app.NumericEditField5.Value;
            offsetmirror = app.NumericEditField6.Value;
            mirror2pivot = app.NumericEditField8.Value;
            headx = app.NumericEditField9.Value;
            heady = app.NumericEditField10.Value;
            figure1 = app.UIAxes2;
            figure2 = app.UIAxes;
            figure3 = app.UIAxes3;
               cla(figure1); cla(figure2); cla(figure3);
            texx = simulate(anglebetweenmirrors, isIdealCamera, distancecamera, fovcamera,...
                lengthmirror, pivot2pivotdistance, offsetmirror, mirror2pivot, headx, heady, figure1, figure2, figure3);
            app.TextArea.Value = texx;
        end

        % CheckBox value changed function
        function changeText(app)
            if strcmp(app.CheckBox.Text,'True')
                app.CheckBox.Text = 'False';
            else
                app.CheckBox.Text = 'True';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Angle';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [5 85];
            app.Slider.MajorTicks = [5 7 9 11 13 15 17 19 21 23 25 27 29 31 33 35 37 39 41 43 45 47 49 51 53 55 57 59 61 63 65 67 69 71 73 75 77 79 81 83 85];
            app.Slider.Value = 71.5;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'SMILE PROJECT';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Facial Mirror System Simulator';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @changeText);
            app.CheckBox.Text = 'True';
            app.CheckBox.Value = true;
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Is Ideal Camera?';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Distance Camera';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField.Limits = [120 500];
            app.NumericEditField.Value = 120;
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'FOV Camera';
            app.NumericEditField3 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField3.Limits = [20 180];
            app.NumericEditField3.Value = 50;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @run);
            app.Button.Text = 'Simulate';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Sistem Parameters';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Length Mirror';
            app.NumericEditField4 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField4.Value = 65;
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Pivot to Pivot Distance';
            app.NumericEditField5 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField5.Value = 28.5;
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'Offset to Mirror';
            app.NumericEditField6 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField6.Value = 3;
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Mirror 2 Pivot';
            app.NumericEditField8 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField8.Value = 2.5;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Visible Heads';
            app.UIAxes = uiaxes(app.Tab);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Full Configuration';
            app.UIAxes2 = uiaxes(app.Tab2);
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Evaluation';
            app.UIAxes3 = uiaxes(app.Tab3);
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.TextArea = uitextarea(app.UIFigure);
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'Head X';
            app.NumericEditField9 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField9.Limits = [10 180];
            app.NumericEditField9.Value = 14.25;
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'Head Y';
            app.NumericEditField10 = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField10.Limits = [10 180];
            app.NumericEditField10.Value = 14;
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end